classdef fowlerFlap_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        MovavgfilternumberoftermsEditField  matlab.ui.control.NumericEditField
        MovavgfilternumberoftermsEditFieldLabel  matlab.ui.control.Label
        DatapathiscurrentlyhardcodedTODOaddfilebrowserLabel  matlab.ui.control.Label
        LoaddatafirstLabel         matlab.ui.control.Label
        ElevatorDeflDropDown       matlab.ui.control.DropDown
        ElevatorDeflDropDownLabel  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Label_2                    matlab.ui.control.Label
        LoadDataButton             matlab.ui.control.Button
        WingProfileDropDown        matlab.ui.control.DropDown
        WingProfileDropDownLabel   matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        runInfoMat % Description
        runTrimmedTraces % Description
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            inds = (app.runInfoMat(:,3) == str2double(app.WingProfileDropDown.Value)) & ...
                (app.runInfoMat(:,4) == str2double(app.ElevatorDeflDropDown.Value));
            inds = find(inds);
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            for q = 1:length(inds)
               plot(app.UIAxes, app.runTrimmedTraces{inds(q)}.tension.Time - app.runTrimmedTraces{inds(q)}.tension.Time(1), app.runTrimmedTraces{inds(q)}.tension.Data, ...
                   'DisplayName', sprintf('Run %d', q));
                
               filteredRaftVelo = movmean(app.runTrimmedTraces{inds(q)}.raftVelo.Data, app.MovavgfilternumberoftermsEditField.Value); % moving average filter, 0.5 sec window
               normalizedTensData = app.runTrimmedTraces{inds(q)}.tension.Data ./ (filteredRaftVelo .^ 2);
               if mean(normalizedTensData) > 1e10
                   normalizedTensData = normalizedTensData.*0;
               end
               plot(app.UIAxes_2, app.runTrimmedTraces{inds(q)}.tension.Time - app.runTrimmedTraces{inds(q)}.tension.Time(1), normalizedTensData, ...
                   'DisplayName', sprintf('Run %d', q));
            end
            legend(app.UIAxes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WingProfileDropDown
        function WingProfileDropDownValueChanged(app, event)
            inds = app.runInfoMat(:,3) == str2double(app.WingProfileDropDown.Value);
            elevDefls = unique(app.runInfoMat(inds,4));
            %newText = sprintf('%d, ', elevDefls);
            %newText(end-1:end) = [];
            %app.TextArea.Value = newText;
            app.ElevatorDeflDropDown.Items = string(num2str(elevDefls));
            updatePlots(app);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.runInfoMat = load('C:\Users\Carson\OneDrive - North Carolina State University\iSSRL\Research\Morphing Wing\2023-07-15_MorphingData\fowlerFlap\runInfoMat_total.mat');
            app.runInfoMat = app.runInfoMat.runInfoMat_total;
            app.runTrimmedTraces = load('C:\Users\Carson\OneDrive - North Carolina State University\iSSRL\Research\Morphing Wing\2023-07-15_MorphingData\fowlerFlap\runTrimmedTraces_total.mat');
            app.runTrimmedTraces = app.runTrimmedTraces.runTrimmedTraces_total;
            app.Lamp.Color = [0 1 0];
            app.WingProfileDropDown.Enable = "on";
            app.ElevatorDeflDropDown.Enable = "on";
            app.MovavgfilternumberoftermsEditField.Enable = "on";

            wingProfiles = unique(app.runInfoMat(:,3));
            %newText = sprintf('%d, ', elevDefls);
            %newText(end-1:end) = [];
            %app.TextArea.Value = newText;
            app.WingProfileDropDown.Items = string(num2str(wingProfiles));
            app.WingProfileDropDownValueChanged();
        end

        % Value changed function: ElevatorDeflDropDown
        function ElevatorDeflDropDownValueChanged(app, event)
            updatePlots(app);
        end

        % Value changed function: MovavgfilternumberoftermsEditField
        function MovavgfilternumberoftermsEditFieldValueChanged(app, event)
            updatePlots(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {955, 955};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WingProfileDropDownLabel = uilabel(app.LeftPanel);
            app.WingProfileDropDownLabel.Text = 'Wing Profile';
            app.WingProfileDropDown = uidropdown(app.LeftPanel);
            app.WingProfileDropDown.Items = {'1000', '1004', '1152', '1154', '1304'};
            app.WingProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @WingProfileDropDownValueChanged, true);
            app.WingProfileDropDown.Enable = 'off';
            app.WingProfileDropDown.Value = '1000';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.LeftPanel);
            app.ElevatorDeflDropDownLabel = uilabel(app.LeftPanel);
            app.ElevatorDeflDropDownLabel.Text = 'Elevator Defl.';
            app.ElevatorDeflDropDown = uidropdown(app.LeftPanel);
            app.ElevatorDeflDropDown.Items = {'0'};
            app.ElevatorDeflDropDown.ValueChangedFcn = createCallbackFcn(app, @ElevatorDeflDropDownValueChanged, true);
            app.ElevatorDeflDropDown.Enable = 'off';
            app.ElevatorDeflDropDown.Value = '0';
            app.LoaddatafirstLabel = uilabel(app.LeftPanel);
            app.LoaddatafirstLabel.Text = 'Load data first!';
            app.DatapathiscurrentlyhardcodedTODOaddfilebrowserLabel = uilabel(app.LeftPanel);
            app.DatapathiscurrentlyhardcodedTODOaddfilebrowserLabel.Text = {'Data path is currently hardcoded'; 'TODO: add file browser'};
            app.MovavgfilternumberoftermsEditFieldLabel = uilabel(app.LeftPanel);
            app.MovavgfilternumberoftermsEditFieldLabel.Text = {'Mov. avg. filter'; 'number of terms'};
            app.MovavgfilternumberoftermsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MovavgfilternumberoftermsEditField.Limits = [1 1000];
            app.MovavgfilternumberoftermsEditField.ValueChangedFcn = createCallbackFcn(app, @MovavgfilternumberoftermsEditFieldValueChanged, true);
            app.MovavgfilternumberoftermsEditField.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Tension Traces')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Tension (N)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Velocity Normalized Tension Traces')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Tension / vel^2 (Ns^2/m^2)')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fowlerFlap_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end